classdef DualOpto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        PinLaser2                       matlab.ui.control.NumericEditField
        PinEditField_2Label             matlab.ui.control.Label
        PinLaser1                       matlab.ui.control.NumericEditField
        PinEditFieldLabel               matlab.ui.control.Label
        OffsetAt2                       matlab.ui.control.NumericEditField
        StopAtLabel_2                   matlab.ui.control.Label
        OffsetAt1                       matlab.ui.control.NumericEditField
        StopAtLabel                     matlab.ui.control.Label
        PhaseDiff                       matlab.ui.control.NumericEditField
        PhaseDiffpiLabel                matlab.ui.control.Label
        Wavelength2                     matlab.ui.control.NumericEditField
        ExpDurationsEditField_2Label_3  matlab.ui.control.Label
        Port2                           matlab.ui.control.EditField
        PortEditField_2Label            matlab.ui.control.Label
        DutyCycle2                      matlab.ui.control.NumericEditField
        DutyLenghtsLabel                matlab.ui.control.Label
        ExpDuration2                    matlab.ui.control.NumericEditField
        ExpDurationsEditFieldLabel      matlab.ui.control.Label
        PulseWidth2                     matlab.ui.control.NumericEditField
        PulseWidthmsEditField_2Label    matlab.ui.control.Label
        Delays2                         matlab.ui.control.NumericEditField
        OnsetDelaysLabel_2              matlab.ui.control.Label
        PulseFreq2                      matlab.ui.control.NumericEditField
        PulseFreqHzEditField_2Label     matlab.ui.control.Label
        CycleDuration2                  matlab.ui.control.NumericEditField
        CycleDurationsEditField_4Label  matlab.ui.control.Label
        PreviewButton                   matlab.ui.control.Button
        Stim2Label                      matlab.ui.control.Label
        SameAsStim1                     matlab.ui.control.CheckBox
        Wavelength1                     matlab.ui.control.NumericEditField
        ExpDurationsEditField_2Label_2  matlab.ui.control.Label
        Port1                           matlab.ui.control.EditField
        PortEditFieldLabel              matlab.ui.control.Label
        DutyCycle1                      matlab.ui.control.NumericEditField
        DutyCyclesLabel                 matlab.ui.control.Label
        PreviewButton1                  matlab.ui.control.Button
        ExpDuration1                    matlab.ui.control.NumericEditField
        ExpDurationsEditField_2Label    matlab.ui.control.Label
        Stim1Label                      matlab.ui.control.Label
        PulseWidth1                     matlab.ui.control.NumericEditField
        PulseWidthmsEditFieldLabel      matlab.ui.control.Label
        Delays1                         matlab.ui.control.NumericEditField
        OnsetDelaysLabel                matlab.ui.control.Label
        PulseFreq1                      matlab.ui.control.NumericEditField
        PulseFreqHzEditFieldLabel       matlab.ui.control.Label
        CycleDuration1                  matlab.ui.control.NumericEditField
        CycleDurationsEditField_3Label  matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        StatusLabel                     matlab.ui.control.Label
        Stim2CheckBox                   matlab.ui.control.CheckBox
        Stim1CheckBox                   matlab.ui.control.CheckBox
        RepeatButton                    matlab.ui.control.Button
        RepeatEditField                 matlab.ui.control.NumericEditField
        PauseButton                     matlab.ui.control.StateButton
        UnoLamp                         matlab.ui.control.Lamp
        UnoLampLabel                    matlab.ui.control.Label
        StopWatch                       matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        Preview2                        matlab.ui.control.UIAxes
        Preview1                        matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        CurrentLogFile                  matlab.ui.control.Label
        WriteNoteButton                 matlab.ui.control.Button
        NoteTextArea                    matlab.ui.control.TextArea
        NoteTextAreaLabel               matlab.ui.control.Label
        PathButton                      matlab.ui.control.Button
        SavePath                        matlab.ui.control.EditField
        Mouse2ID                        matlab.ui.control.EditField
        Mouse2IDEditFieldLabel          matlab.ui.control.Label
        Mouse1ID                        matlab.ui.control.EditField
        Mouse1IDEditFieldLabel          matlab.ui.control.Label
        ExpDate                         matlab.ui.control.EditField
        ExpDateEditFieldLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        y2 % stim pulse train for stim channel 2
        y1 % stim pulse train for stim channel 1
        timeAx % temporal axis  bigger of the 2 exp durations
        timetotal % total time
        line1 % handle of line in preview1
        line2 %handle of line in preview2
        stopflag = false% detect stop button pushed
        rate % loop rate control obj
        stimCOM1 % COM for channel1
        stimCOM2 % COM for channel2
        pin1 % channel1 pin
        pin2 % channel2 pin
        serialObj % serial port
        outstring % last serial command to arduino
        pinLaser1 = 4 % Laser channel1 on arduino, match arduino, do not change
        pinLaser2 = 8 % Laser channel2 on arduino, match arduino, do not change
        Stim1Pin % Description
        Stim2Pin % Description
        resetflag = false % reset flag
        repeatflag = false % repeat state
        runflag % flag indicate current run state
        logfile % path to current log file
    end
    
    methods (Access = private)
        
        function outstring = ArdCommand(app)
            wavelength = app.Wavelength1.Value;
            expduration = app.timetotal;
            cycleduration = app.CycleDuration1.Value;
            dutylength = app.DutyCycle1.Value;
            pulsefreq = app.PulseFreq1.Value;
            pulsewidth = app.PulseWidth1.Value;
            startat1 = app.Delays1.Value;
            startat2 = app.Delays2.Value;
            stopat1 = app.OffsetAt1.Value;
            stopat2 = app.OffsetAt2.Value;
            pinLaser1 = app.PinLaser1.Value;
            pinLaser2 = app.PinLaser2.Value;
            strcell = {wavelength,expduration, cycleduration, dutylength, pulsefreq, pulsewidth, startat1, stopat1,startat2,stopat2,pinLaser1,pinLaser2};
            strcell = cellfun(@num2str,strcell, 'UniformOutput',false);
            outstring = ['p',strjoin(strcell,','),',']; %p473,20,6,4,20,20,3,18,1,12,4,8,   wavelength, expduration, cycleduration, dutylength, pulsefreq, pulsewidth, startat1, stopat1, startat2, stopat2, pinLaser1, pinLaser2


        end
        
        function WriteLog(app)
            Mouse1 = app.Mouse1ID.Value; Mouse2 = app.Mouse2ID.Value;
            expdate = app.ExpDate.Value;
            exptime = string(datetime(clock),'hh:mm:ss');
            targetdir = app.SavePath.Value;
            
            if ~exist([targetdir,'\',expdate],'dir')
                mkdir([targetdir,'\',expdate]);
            end
            fname = [Mouse1,'-', Mouse2];
            app.logfile = [targetdir,'\',expdate,'\',fname,'.txt'];
            fid = fopen(app.logfile,'a');  % targetdir/date/M1-M2.txt, then append each run with the same animal pair in the same txt file
            if fid>0

                [explog, parameter1, parameter2] = LogGen(app);
                fprintf(fid,'%s\n', exptime); % print time first
                fprintf(fid,'Mouse1 Parameters:\n');
                fprintf(fid, explog, parameter1);
                fprintf(fid,'Mouse2 Parameters:\n');
                fprintf(fid, explog, parameter2);
                arduinostate = app.SameAsStim1.Value;
                if arduinostate
                    fprintf(fid,'Laser engaged! Phase diff(in 2pi) = %4.2f \n', app.PhaseDiff.Value);
                else
                    fprintf(fid,'Laser NOT engaged! Sim only. \n');
                end
                if app.Stim1CheckBox.Value
                    fprintf(fid, 'Stim1 engaged!\n');
                else
                    fprintf(fid, 'Stim1 IDLE! \n');
                end
                if app.Stim2CheckBox.Value
                    fprintf(fid, 'Stim2 engaged!\n');
                else
                    fprintf(fid, 'Stim2 IDLE!\n');
                end
                fclose(fid);
            else
                warndlg('Failed to write the log!');
            end
        end
        
        function [explog, parameter1, parameter2] = LogGen(app)
            wavelength1 = app.Wavelength1.Value; wavelength2 = app.Wavelength2.Value;
            expduration1 = app.ExpDuration1.Value; expduration2 = app.ExpDuration2.Value;
            cycleduration1 = app.CycleDuration1.Value; cycleduration2 = app.CycleDuration2.Value;
            dutylength1 = app.DutyCycle1.Value; dutylength2 = app.DutyCycle2.Value;
            pulsefreq1 = app.PulseFreq1.Value; pulsefreq2 = app.PulseFreq2.Value;
            pulsewidth1 = app.PulseWidth1.Value; pulsewidth2 = app.PulseWidth2.Value;
            startat1 = app.Delays1.Value; startat2 = app.Delays2.Value;
            stopat1 = app.OffsetAt1.Value; stopat2 = app.OffsetAt2.Value;
            pinLaser1 = app.PinLaser1.Value; pinLaser2 = app.PinLaser2.Value;
            explog = 'wavelength=%d, expduration=%d, cycleduration=%5.2f, dutylength=%5.2f, pulsefreq=%d, pulsewidth=%d, startat=%5.2f, stopat=%5.2f, pinLaser=%d \r\n';
            parameter1 = [wavelength1, expduration1, cycleduration1, dutylength1, pulsefreq1, pulsewidth1, startat1, stopat1, pinLaser1];
            parameter2 = [wavelength2, expduration2, cycleduration2, dutylength2, pulsefreq2, pulsewidth2, startat2, stopat2, pinLaser2];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % detect arduino
            try
                portName = serialportlist("available");
                portName = portName(1);
                app.serialObj = serialport(portName, 9600); % only deal with 1 arduino board at this stage
                app.UnoLamp.Color = [0,1,0];
                app.UnoLampLabel.Text = strcat('UNO-', portName);
                app.Port1.Value = portName;
                app.Port2.Value = portName;
            catch ME
                app.UnoLampLabel.Text = 'Not Available';
            end
            app.timetotal = max(app.ExpDuration1.Value, app.ExpDuration2.Value);
            app.Stim1Pin = app.PinLaser1.Value;
            app.Stim2Pin = app.PinLaser2.Value;
            app.ExpDate.Value = string(datetime(clock), 'yyyy-MM-dd');
            app.SavePath.Value = pwd;
        end

        % Button pushed function: PreviewButton1
        function PreviewButton1Pushed(app, event)
            expDur = app.ExpDuration1.Value; % second
            cycDur = app.CycleDuration1.Value; % second
            dutyCyc = app.DutyCycle1.Value; % second
            plsFreq = app.PulseFreq1.Value; % Hz
            plsWid = app.PulseWidth1.Value; % ms
            trainDelay = app.Delays1.Value; % s
            stopat = app.OffsetAt1.Value;
            if cycDur <= dutyCyc
                dutyCyc = cycDur;
            end
            t = 0:0.001:expDur;
            app.timeAx = t;
            app.Preview1.XLim = [0, expDur];
%             yBig = pulstran(t,[trainDelay:cycDur:stopat-dutyCyc]+1/2*dutyCyc,"rectpuls",dutyCyc);
            yBig = zeros([1,numel(t)]);
            duty = trainDelay:cycDur:stopat;
            ySmall = pulstran(0:0.001:dutyCyc-0.001,0:1/plsFreq:dutyCyc,'rectpuls',plsWid/1000); % length = dutyCyc * 1000
            for i = 1:numel(duty)
                if (duty(i)+dutyCyc)*1000 <= round(stopat*1000)
                    yBig(duty(i)*1000+1:(duty(i)+dutyCyc)*1000) = ySmall;
                else
                    yBig(duty(i)*1000+1:round(stopat*1000)) = ySmall(1:round(stopat*1000)-duty(i)*1000);
                end
            end
            y = yBig;

%             y = yBig.*ySmall; % too slow
            plot(app.Preview1, t, y, 'b-');
            app.Preview1.XLim = [0, app.timetotal];
            app.Preview2.XLim = [0, app.timetotal];
            app.y1 = y;
            app.Preview1.NextPlot = 'add';
            app.line1 = line(app.Preview1,[0,0],app.Preview1.YLim,'Color','r'); app.Preview1.NextPlot = 'replacechildren';
        end

        % Button pushed function: PreviewButton
        function PreviewButton_2Pushed(app, event)
            expDur = app.ExpDuration2.Value; % second
            cycDur = app.CycleDuration2.Value; % second
            dutyCyc = app.DutyCycle2.Value; % second
            plsFreq = app.PulseFreq2.Value; % Hz
            plsWid = app.PulseWidth2.Value; % ms
            trainDelay = app.Delays2.Value; % s
            stopat = app.OffsetAt2.Value;
            if cycDur <= dutyCyc
                dutyCyc = cycDur;
            end
            t = 0:0.001:expDur;
            app.Preview2.XLim = [0, expDur];
%             yBig = pulstran(t,[trainDelay:cycDur:expDur]+1/2*dutyCyc,"rectpuls",dutyCyc);
            yBig = zeros([1,numel(t)]);
            duty = trainDelay:cycDur:stopat;
            ySmall = pulstran(0:0.001:dutyCyc-0.001,0:1/plsFreq:dutyCyc,'rectpuls',plsWid/1000); % length = dutyCyc * 1000
            for i = 1:numel(duty)
                if (duty(i)+dutyCyc)*1000 <= round(stopat*1000)
                    yBig(duty(i)*1000+1:(duty(i)+dutyCyc)*1000) = ySmall;
                else
                    yBig(duty(i)*1000+1:round(stopat*1000)) = ySmall(1:round(stopat*1000)-duty(i)*1000);
                end
            end
            y = yBig;

%             y = yBig.*ySmall; % too slow
            plot(app.Preview2, t, y, 'b-');
            app.Preview2.XLim = [0,app.timetotal];
            app.Preview1.XLim = [0,app.timetotal];
            app.y2 = y;
            app.Preview2.NextPlot = 'add';
            app.line2 = line(app.Preview2,[0,0],app.Preview2.YLim,'Color','r'); app.Preview2.NextPlot = "replacechildren";
        end

        % Value changed function: SameAsStim1
        function SameAsStim1ValueChanged(app, event)
            value = app.SameAsStim1.Value;
            if value
                app.DutyCycle2.Value = app.DutyCycle1.Value; app.DutyCycle2.Editable = 'off';
                app.ExpDuration2.Value = app.ExpDuration1.Value; app.ExpDuration2.Editable = 'off';
                app.PulseFreq2.Value = app.PulseFreq1.Value; app.PulseFreq2.Editable = 'off';
                app.CycleDuration2.Value = app.CycleDuration1.Value; app.CycleDuration2.Editable = 'off';
                app.PulseWidth2.Value = app.PulseWidth1.Value; app.PulseWidth2.Editable = 'off';
                app.PhaseDiffpiLabel.Enable = 'on';
                app.PhaseDiff.Enable = 'on';
                app.PhaseDiff.Value = rem(app.Delays2.Value-app.Delays1.Value,app.CycleDuration1.Value);
                app.timetotal = max(app.ExpDuration1.Value, app.ExpDuration2.Value);
                app.timeAx = 0:0.001:app.timetotal;
                app.Delays2.Limits = [0, app.ExpDuration2.Value]; app.OffsetAt2.Limits = [0, app.ExpDuration2.Value];
            else
                app.DutyCycle2.Editable = 'on';
                app.ExpDuration2.Editable = 'on';
                app.PulseFreq2.Editable = 'on';
                app.CycleDuration2.Editable = 'on';
                app.PulseWidth2.Editable = 'on';
                app.PhaseDiff.Enable = 'off';
                app.PhaseDiffpiLabel.Enable = 'off';
            end
            
        end

        % Value changed function: ExpDuration1
        function ExpDuration1ValueChanged(app, event)
            value = app.ExpDuration1.Value;
            app.OffsetAt1.Limits = [0, value];
            app.Delays1.Limits = [0, value];
            if app.SameAsStim1.Value
                app.ExpDuration2.Value = value;
                app.OffsetAt2.Limits = [0, value];
                app.Delays2.Limits = [0, value];
            end
            
            app.timetotal = max(value, app.ExpDuration2.Value);
            app.timeAx = 0:0.001:app.timetotal;
        end

        % Value changed function: ExpDuration2
        function ExpDuration2ValueChanged(app, event)
            value = app.ExpDuration2.Value;
            app.OffsetAt2.Limits = [0, value];
            app.Delays2.Limits = [0, value];
            app.timetotal = max(value, app.ExpDuration1.Value);
            app.timeAx = 0:0.001:app.timetotal;
        end

        % Value changed function: CycleDuration1
        function CycleDuration1ValueChanged(app, event)
            value = app.CycleDuration1.Value;
            if app.SameAsStim1.Value
                app.CycleDuration2.Value = value;
            end
        end

        % Value changed function: DutyCycle1
        function DutyCycle1ValueChanged(app, event)
            value = app.DutyCycle1.Value;
            if app.SameAsStim1.Value
                app.DutyCycle2.Value = value;
            end
        end

        % Value changed function: PulseFreq1
        function PulseFreq1ValueChanged(app, event)
            value = app.PulseFreq1.Value;
            if app.SameAsStim1.Value
                app.PulseFreq2.Value = value;
            end
        end

        % Value changed function: PulseWidth1
        function PulseWidth1ValueChanged(app, event)
            value = app.PulseWidth1.Value;
            if app.SameAsStim1.Value
                app.PulseWidth2.Value = value;
            end
        end

        % Value changed function: PhaseDiff
        function PhaseDiffValueChanged(app, event)
            phasediff = app.PhaseDiff.Value;
            app.Delays2.Value = app.Delays1.Value + phasediff * app.CycleDuration1.Value; 
        end

        % Value changed function: Delays2
        function Delays2ValueChanged(app, event)
            value = app.Delays2.Value;
            if app.SameAsStim1.Value
                app.PhaseDiff.Value = round((app.Delays2.Value - app.Delays1.Value)/app.CycleDuration1.Value,2);
            end
        end

        % Value changed function: Delays1
        function Delays1ValueChanged(app, event)
            value = app.Delays1.Value;
            if app.SameAsStim1.Value
                app.PhaseDiff.Value = round((app.Delays2.Value - app.Delays1.Value)/app.CycleDuration1.Value,2);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % detect if there is ongoing run
            % if so, reset and start fresh
        
            ResetButtonPushed(app, event);
            app.resetflag = false;
            app.RepeatButton.Enable = "off";
            app.StartButton.Enable = "off";
            outstring = ArdCommand(app);
            app.outstring = outstring;
            if app.SameAsStim1.Value
                app.StatusLabel.Text = ['Busy, arduino = ', outstring];
                app.StatusLabel.FontColor = [0,1,0];
                try 
                    writeline(app.serialObj,outstring);
                catch ME
                    app.StatusLabel.Text = 'Failed to connect!'
                    app.StatusLabel.FontColor = [1,0,0];
                end
            else
                app.StatusLabel.Text = 'Preview only, check SameasStim1 to send arduino command!';
                app.StatusLabel.FontColor = [1,0,0];
            end
            app.PauseButton.Enable = 'on';
            app.PauseButton.Value = 0;
            ratehz = 50;
            app.rate = rateControl(ratehz);
            app.runflag = true;
            reset(app.rate);
            interrupt = false; % interrupted by user?
            for i = 1:round(1000/ratehz):numel(app.timeAx)  % 50hz
                time = app.rate.TotalElapsedTime;
                time_dur = duration(seconds(time));
                time_dur_str = string(time_dur,'hh:mm:ss.SS');
                app.StopWatch.Text = time_dur_str;
                app.line1.XData = [app.timeAx(i),app.timeAx(i)]; app.line2.XData = [app.timeAx(i),app.timeAx(i)];
                waitfor(app.rate);

                %% interupt behavior, user press stop, reset or start
                if app.stopflag % clicked stop during single run
                    app.stopflag = false;
%                     ResetButtonPushed(app,event);
                    interrupt = true;
                    break
                end
                if app.resetflag % clicked reset during single run
                    app.resetflag = false;
                    interrupt = true;
                    break
                end

            end
            WriteLog(app);
            if interrupt
                fid = fopen(app.logfile,'a');
                fprintf(fid, 'Single run. Interrupted by User at %s!\n', time_dur_str);
                fclose(fid);
            else
                fid = fopen(app.logfile,'a');
                fprintf(fid, 'Single run success!\n');
                fclose(fid);
            end
            app.StatusLabel.Text = 'Feels cool!';
            app.StatusLabel.FontColor = [0,0,0];
            app.PauseButton.Enable = "off";
            app.RepeatButton.Enable = "on";
            app.StartButton.Enable = "on";
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.PauseButton.Value
                app.PauseButton.Value = false;
                uiresume(app.UIFigure);
            end
            if app.SameAsStim1.Value
                try
                    writeline(app.serialObj,'s,');
                end
            end
            app.PauseButton.Enable = 'off';
            app.stopflag = true;
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            value = app.PauseButton.Value;
            if app.SameAsStim1.Value
                try
                    writeline(app.serialObj,'q,');
                end
            end
            if and(~value, app.SameAsStim1.Value)
                app.StatusLabel.Text = ['Busy, arduino = ', app.outstring];
                uiresume(app.UIFigure);
            elseif and(~value, ~app.SameAsStim1.Value)
                app.StatusLabel.Text = ['Preview only, check SameasStim1 to send arduino command!'];
                uiresume(app.UIFigure);
            elseif and(value, app.SameAsStim1.Value)
                app.StatusLabel.Text = ['PAUSED, arduino = ',app.outstring];
                uiwait(app.UIFigure);
            elseif and(value, ~app.SameAsStim1.Value)
                app.StatusLabel.Text = ['Preview PAUSED!'];
                uiwait(app.UIFigure);
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if app.PauseButton.Value
                app.PauseButton.Value = false;
                uiresume(app.UIFigure);
            end
            app.resetflag = true;
            app.stopflag = false;
            app.rate = [];
            if app.SameAsStim1.Value
                try
                    writeline(app.serialObj,'s,');
                end
            end
            app.PauseButton.Value = 0;
            app.PauseButton.Enable = 'off';
            app.line1.XData = [0,0];
            app.line2.XData = [0,0];
            app.StopWatch.Text = '00:00:00.00';
            app.StatusLabel.Text = 'Feels cool!';
            app.StatusLabel.FontColor = [0,0,0];
        end

        % Value changed function: RepeatEditField
        function RepeatEditFieldValueChanged(app, event)
            value = app.RepeatEditField.Value;
            if value < 1
                app.RepeatButton.Enable = "off";
            else
                app.RepeatButton.Enable = "on";
            end
        end

        % Button pushed function: RepeatButton
        function RepeatButtonPushed(app, event)
            numrepts = app.RepeatEditField.Value;
            app.StartButton.Enable = "off";
            outstring = ArdCommand(app);
            app.outstring = outstring;
            ratehz = 50;
            app.repeatflag = true;
            app.RepeatButton.Enable = "off";
            for k = 1:numrepts
                app.RepeatEditField.Value = k;
                app.RepeatEditField.FontColor = [0,1,0];

                ResetButtonPushed(app, event); % reset after each repeat
                
                app.resetflag = false; 

                if app.SameAsStim1.Value
                    app.StatusLabel.Text = ['Busy, arduino = ', outstring];
                    app.StatusLabel.FontColor = [0,1,0];
                    try 
                        writeline(app.serialObj,outstring);
                    catch ME
                        app.StatusLabel.Text = 'Failed to connect!'
                        app.StatusLabel.FontColor = [1,0,0];
                    end
                else
                    app.StatusLabel.Text = 'Preview only, check SameasStim1 to send arduino command!';
                    app.StatusLabel.FontColor = [1,0,0];
                end
                app.PauseButton.Enable = 'on';
                app.PauseButton.Value = 0;

                app.rate = rateControl(ratehz);
                reset(app.rate);
                %% start running stim
                for i = 1:round(1000/ratehz):numel(app.timeAx)  % 50hz
                    time = app.rate.TotalElapsedTime;
                    time_dur = duration(seconds(time));
                    time_dur_str = string(time_dur,'hh:mm:ss.SS');
                    app.StopWatch.Text = time_dur_str;
                    app.line1.XData = [app.timeAx(i),app.timeAx(i)]; app.line2.XData = [app.timeAx(i),app.timeAx(i)];
                    waitfor(app.rate);
                    if app.stopflag % click stop during repeat running
                        app.stopflag = false;
                        app.repeatflag = false;
                        app.StartButton.Enable = "on";
                        app.PauseButton.Enable = "off";
                        break
                    end
                    if app.resetflag % click reset during repeat running
                        app.resetflag = false;
                        app.repeatflag = false;
                        app.StartButton.Enable = "on";
                        app.PauseButton.Enable = "off";

                        break
                    end
                end % end of running stim
                if ~app.repeatflag % repeat interrupted
                   break % break big loop
                end
            end
                %% end of running repeats
            if ~app.repeatflag % interrupted by user
                fid = fopen(app.logfile,'a');
                fprintf(fid, 'Repeats interrupted by User at at repeat# %d/%d, %s \n', k, numrepts, time_dur_str);
                fclose(fid);
            else
                fid = fopen(app.logfile,'a');
                fprintf(fid, 'Repeats success! # of repeats = %d \n', numrepts);
                fclose(fid);
            end
            app.StatusLabel.Text = 'Feels cool!';
            app.StatusLabel.FontColor = [0,0,0];
            app.PauseButton.Enable = "off";
            app.StartButton.Enable = "on";
            app.repeatflag = false;
            app.RepeatButton.Enable = "on";
            app.RepeatEditField.Value = numrepts;
            app.RepeatEditField.FontColor = [0,0,0];
            app.rate = [];
        end

        % Value changed function: Port1
        function Port1ValueChanged(app, event)
            change_port = app.Port1.Value;
            app.serialObj = [];
            try
                app.serialObj = serialport(change_port,9600);
                app.UnoLamp.Color = [0,1,0];
                app.UnoLampLabel.Text = strcat('UNO-', change_port);
            catch ME
                app.UnoLamp.Color = [1,0,0];
                app.UnoLampLabel.Text = 'Disconnected!';
            end
        end

        % Value changed function: Stim1CheckBox
        function Stim1CheckBoxValueChanged(app, event)
            value = app.Stim1CheckBox.Value;
            if ~value
                idlePins = setdiff(1:13, [app.pinLaser1,app.pinLaser2]);
                app.PinLaser1.Value = idlePins(1);
                app.PinLaser1.Enable = "off";
            else
                app.PinLaser1.Value = app.Stim1Pin;
                app.PinLaser1.Enable = "on";
            end
            
        end

        % Value changed function: Stim2CheckBox
        function Stim2CheckBoxValueChanged(app, event)
            value = app.Stim2CheckBox.Value;
            if ~value
                idlePins = setdiff(1:13, [app.pinLaser1,app.pinLaser2]);
                app.PinLaser2.Value = idlePins(2);
                app.PinLaser2.Enable = "off";
            else
                app.PinLaser2.Value = app.Stim2Pin;
                app.PinLaser2.Enable = "on";
            end
            
        end

        % Value changed function: PinLaser1
        function PinLaser1ValueChanged(app, event)
            value = app.PinLaser1.Value;
            if value == app.pinLaser1 | value == app.pinLaser2
                app.Stim1CheckBox.Value = true;
                app.Stim1CheckBox.Enable = "on";
                app.Stim1Pin = value;
            else
                app.Stim1CheckBox.Value = false;
                app.Stim1CheckBox.Enable = "off";
            end
        end

        % Value changed function: PinLaser2
        function PinLaser2ValueChanged(app, event)
            value = app.PinLaser2.Value;
            if value == app.pinLaser1 | value == app.pinLaser2
                app.Stim2CheckBox.Value = true;
                app.Stim2CheckBox.Enable = "on";
                app.Stim2Pin = value;
            else
                app.Stim2CheckBox.Value = false;
                app.Stim2CheckBox.Enable = 'off';
            end
        end

        % Button pushed function: PathButton
        function PathButtonPushed(app, event)
            dname = uigetdir([],'select save path');
            app.SavePath.Value = dname;
        end

        % Button pushed function: WriteNoteButton
        function WriteNoteButtonPushed(app, event)
            ExpNote = app.NoteTextArea.Value;
            Mouse1 = app.Mouse1ID.Value; Mouse2 = app.Mouse2ID.Value;
            expdate = app.ExpDate.Value;
            exptime = string(datetime(clock),'hh:mm:ss');
            targetdir = app.SavePath.Value;
            
            if ~exist([targetdir,'\',expdate],'dir')
                mkdir([targetdir,'\',expdate]);
            end
            fname = [Mouse1,'-', Mouse2];
            app.logfile = [targetdir,'\',expdate,'\',fname,'.txt'];
            fid = fopen(app.logfile,'a');  % targetdir/date/M1-M2.txt, then append each run with the same animal pair in the same txt file
            if fid > 0    
                fprintf(fid,'Experiment Note at %s\n', exptime);
                fprintf(fid, '%s\n',ExpNote{:});
                fclose(fid);
            else
                warndlg('Not able to write. Failed to open log file!');
            end
        end

        % Value changed function: Mouse1ID
        function Mouse1IDValueChanged(app, event)
            Mouse1 = app.Mouse1ID.Value; Mouse2 = app.Mouse2ID.Value;
            expdate = app.ExpDate.Value;
            targetdir = app.SavePath.Value;
            fname = [Mouse1,'-', Mouse2];
            app.logfile = [targetdir,'\',expdate,'\',fname,'.txt'];
        end

        % Value changed function: Mouse2ID
        function Mouse2IDValueChanged(app, event)
            Mouse1 = app.Mouse1ID.Value; Mouse2 = app.Mouse2ID.Value;
            expdate = app.ExpDate.Value;
            targetdir = app.SavePath.Value;
            fname = [Mouse1,'-', Mouse2];
            app.logfile = [targetdir,'\',expdate,'\',fname,'.txt'];
        end

        % Value changed function: ExpDate
        function ExpDateValueChanged(app, event)
            Mouse1 = app.Mouse1ID.Value; Mouse2 = app.Mouse2ID.Value;
            expdate = app.ExpDate.Value;
            targetdir = app.SavePath.Value;
            fname = [Mouse1,'-', Mouse2];
            app.logfile = [targetdir,'\',expdate,'\',fname,'.txt'];
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {731, 731, 731};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {731, 731};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {241, '1x', 255};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CycleDurationsEditField_3Label = uilabel(app.LeftPanel);
            app.CycleDurationsEditField_3Label.Text = 'CycleDuration(s)';
            app.CycleDuration1 = uieditfield(app.LeftPanel, 'numeric');
            app.CycleDuration1.ValueChangedFcn = createCallbackFcn(app, @CycleDuration1ValueChanged, true);
            app.CycleDuration1.Value = 10;
            app.PulseFreqHzEditFieldLabel = uilabel(app.LeftPanel);
            app.PulseFreqHzEditFieldLabel.Text = 'PulseFreq(Hz)';
            app.PulseFreq1 = uieditfield(app.LeftPanel, 'numeric');
            app.PulseFreq1.ValueChangedFcn = createCallbackFcn(app, @PulseFreq1ValueChanged, true);
            app.PulseFreq1.Value = 20;
            app.OnsetDelaysLabel = uilabel(app.LeftPanel);
            app.OnsetDelaysLabel.Text = 'StartAt';
            app.Delays1 = uieditfield(app.LeftPanel, 'numeric');
            app.Delays1.Limits = [0 60];
            app.Delays1.ValueChangedFcn = createCallbackFcn(app, @Delays1ValueChanged, true);
            app.PulseWidthmsEditFieldLabel = uilabel(app.LeftPanel);
            app.PulseWidthmsEditFieldLabel.Text = 'PulseWidth(ms)';
            app.PulseWidth1 = uieditfield(app.LeftPanel, 'numeric');
            app.PulseWidth1.ValueChangedFcn = createCallbackFcn(app, @PulseWidth1ValueChanged, true);
            app.PulseWidth1.Value = 20;
            app.Stim1Label = uilabel(app.LeftPanel);
            app.Stim1Label.Text = 'Stim1';
            app.ExpDurationsEditField_2Label = uilabel(app.LeftPanel);
            app.ExpDurationsEditField_2Label.Text = 'ExpDuration(s)';
            app.ExpDuration1 = uieditfield(app.LeftPanel, 'numeric');
            app.ExpDuration1.ValueChangedFcn = createCallbackFcn(app, @ExpDuration1ValueChanged, true);
            app.ExpDuration1.Value = 60;
            app.PreviewButton1 = uibutton(app.LeftPanel, 'push');
            app.PreviewButton1.ButtonPushedFcn = createCallbackFcn(app, @PreviewButton1Pushed, true);
            app.PreviewButton1.Text = 'Preview';
            app.DutyCyclesLabel = uilabel(app.LeftPanel);
            app.DutyCyclesLabel.Text = 'DutyLength(s)';
            app.DutyCycle1 = uieditfield(app.LeftPanel, 'numeric');
            app.DutyCycle1.ValueChangedFcn = createCallbackFcn(app, @DutyCycle1ValueChanged, true);
            app.DutyCycle1.Value = 5;
            app.PortEditFieldLabel = uilabel(app.LeftPanel);
            app.PortEditFieldLabel.Text = 'Port#';
            app.Port1 = uieditfield(app.LeftPanel, 'text');
            app.Port1.ValueChangedFcn = createCallbackFcn(app, @Port1ValueChanged, true);
            app.Port1.Value = 'COM3';
            app.ExpDurationsEditField_2Label_2 = uilabel(app.LeftPanel);
            app.ExpDurationsEditField_2Label_2.Text = 'Wavelength';
            app.Wavelength1 = uieditfield(app.LeftPanel, 'numeric');
            app.Wavelength1.Value = 473;
            app.SameAsStim1 = uicheckbox(app.LeftPanel);
            app.SameAsStim1.ValueChangedFcn = createCallbackFcn(app, @SameAsStim1ValueChanged, true);
            app.SameAsStim1.Text = 'SameAsStim1';
            app.Stim2Label = uilabel(app.LeftPanel);
            app.Stim2Label.Text = 'Stim2';
            app.PreviewButton = uibutton(app.LeftPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButton_2Pushed, true);
            app.PreviewButton.Text = 'Preview';
            app.CycleDurationsEditField_4Label = uilabel(app.LeftPanel);
            app.CycleDurationsEditField_4Label.Text = 'CycleDuration(s)';
            app.CycleDuration2 = uieditfield(app.LeftPanel, 'numeric');
            app.CycleDuration2.Value = 10;
            app.PulseFreqHzEditField_2Label = uilabel(app.LeftPanel);
            app.PulseFreqHzEditField_2Label.Text = 'PulseFreq(Hz)';
            app.PulseFreq2 = uieditfield(app.LeftPanel, 'numeric');
            app.PulseFreq2.Value = 20;
            app.OnsetDelaysLabel_2 = uilabel(app.LeftPanel);
            app.OnsetDelaysLabel_2.Text = 'StartAt';
            app.Delays2 = uieditfield(app.LeftPanel, 'numeric');
            app.Delays2.Limits = [0 60];
            app.Delays2.ValueChangedFcn = createCallbackFcn(app, @Delays2ValueChanged, true);
            app.PulseWidthmsEditField_2Label = uilabel(app.LeftPanel);
            app.PulseWidthmsEditField_2Label.Text = 'PulseWidth(ms)';
            app.PulseWidth2 = uieditfield(app.LeftPanel, 'numeric');
            app.PulseWidth2.Value = 20;
            app.ExpDurationsEditFieldLabel = uilabel(app.LeftPanel);
            app.ExpDurationsEditFieldLabel.Text = 'ExpDuration(s)';
            app.ExpDuration2 = uieditfield(app.LeftPanel, 'numeric');
            app.ExpDuration2.ValueChangedFcn = createCallbackFcn(app, @ExpDuration2ValueChanged, true);
            app.ExpDuration2.Value = 60;
            app.DutyLenghtsLabel = uilabel(app.LeftPanel);
            app.DutyLenghtsLabel.Text = 'DutyLength(s)';
            app.DutyCycle2 = uieditfield(app.LeftPanel, 'numeric');
            app.DutyCycle2.Value = 5;
            app.PortEditField_2Label = uilabel(app.LeftPanel);
            app.PortEditField_2Label.Text = 'Port#';
            app.Port2 = uieditfield(app.LeftPanel, 'text');
            app.Port2.Value = 'COM4';
            app.ExpDurationsEditField_2Label_3 = uilabel(app.LeftPanel);
            app.ExpDurationsEditField_2Label_3.Text = 'Wavelength';
            app.Wavelength2 = uieditfield(app.LeftPanel, 'numeric');
            app.Wavelength2.Value = 473;
            app.PhaseDiffpiLabel = uilabel(app.LeftPanel);
            app.PhaseDiffpiLabel.Enable = 'off';
            app.PhaseDiffpiLabel.Text = 'PhaseDiff (2pi)';
            app.PhaseDiff = uieditfield(app.LeftPanel, 'numeric');
            app.PhaseDiff.ValueChangedFcn = createCallbackFcn(app, @PhaseDiffValueChanged, true);
            app.PhaseDiff.Editable = 'off';
            app.PhaseDiff.Enable = 'off';
            app.StopAtLabel = uilabel(app.LeftPanel);
            app.StopAtLabel.Text = 'StopAt';
            app.OffsetAt1 = uieditfield(app.LeftPanel, 'numeric');
            app.OffsetAt1.Limits = [0 60];
            app.OffsetAt1.Value = 60;
            app.StopAtLabel_2 = uilabel(app.LeftPanel);
            app.StopAtLabel_2.Text = 'StopAt';
            app.OffsetAt2 = uieditfield(app.LeftPanel, 'numeric');
            app.OffsetAt2.Limits = [0 60];
            app.OffsetAt2.Value = 60;
            app.PinEditFieldLabel = uilabel(app.LeftPanel);
            app.PinEditFieldLabel.Text = 'Pin';
            app.PinLaser1 = uieditfield(app.LeftPanel, 'numeric');
            app.PinLaser1.ValueChangedFcn = createCallbackFcn(app, @PinLaser1ValueChanged, true);
            app.PinLaser1.Value = 4;
            app.PinEditField_2Label = uilabel(app.LeftPanel);
            app.PinEditField_2Label.Text = 'Pin';
            app.PinLaser2 = uieditfield(app.LeftPanel, 'numeric');
            app.PinLaser2.ValueChangedFcn = createCallbackFcn(app, @PinLaser2ValueChanged, true);
            app.PinLaser2.Value = 8;
            app.CenterPanel = uipanel(app.GridLayout);
            app.Preview1 = uiaxes(app.CenterPanel);
            title(app.Preview1, 'Stim1 Preview')
            ylabel(app.Preview1, 'signal')
            zlabel(app.Preview1, 'Z')
            app.Preview2 = uiaxes(app.CenterPanel);
            title(app.Preview2, 'Stim2 Preview')
            xlabel(app.Preview2, 'time(s)')
            ylabel(app.Preview2, 'signal')
            zlabel(app.Preview2, 'Z')
            app.StartButton = uibutton(app.CenterPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.CenterPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.CenterPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StopWatch = uilabel(app.CenterPanel);
            app.StopWatch.Text = '00:00:00.00';
            app.UnoLampLabel = uilabel(app.CenterPanel);
            app.UnoLampLabel.Text = 'Uno';
            app.UnoLamp = uilamp(app.CenterPanel);
            app.PauseButton = uibutton(app.CenterPanel, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.RepeatEditField = uieditfield(app.CenterPanel, 'numeric');
            app.RepeatEditField.Limits = [0 Inf];
            app.RepeatEditField.ValueChangedFcn = createCallbackFcn(app, @RepeatEditFieldValueChanged, true);
            app.RepeatButton = uibutton(app.CenterPanel, 'push');
            app.RepeatButton.ButtonPushedFcn = createCallbackFcn(app, @RepeatButtonPushed, true);
            app.RepeatButton.Text = 'Repeat';
            app.Stim1CheckBox = uicheckbox(app.CenterPanel);
            app.Stim1CheckBox.ValueChangedFcn = createCallbackFcn(app, @Stim1CheckBoxValueChanged, true);
            app.Stim1CheckBox.Text = 'Stim1';
            app.Stim1CheckBox.Value = true;
            app.Stim2CheckBox = uicheckbox(app.CenterPanel);
            app.Stim2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Stim2CheckBoxValueChanged, true);
            app.Stim2CheckBox.Text = 'Stim2';
            app.Stim2CheckBox.Value = true;
            app.StatusLabel = uilabel(app.CenterPanel);
            app.StatusLabel.Text = 'Feels cool!';
            app.RightPanel = uipanel(app.GridLayout);
            app.ExpDateEditFieldLabel = uilabel(app.RightPanel);
            app.ExpDateEditFieldLabel.Text = 'ExpDate';
            app.ExpDate = uieditfield(app.RightPanel, 'text');
            app.ExpDate.ValueChangedFcn = createCallbackFcn(app, @ExpDateValueChanged, true);
            app.Mouse1IDEditFieldLabel = uilabel(app.RightPanel);
            app.Mouse1IDEditFieldLabel.Text = 'Mouse1-ID';
            app.Mouse1ID = uieditfield(app.RightPanel, 'text');
            app.Mouse1ID.ValueChangedFcn = createCallbackFcn(app, @Mouse1IDValueChanged, true);
            app.Mouse1ID.Value = 'M1';
            app.Mouse2IDEditFieldLabel = uilabel(app.RightPanel);
            app.Mouse2IDEditFieldLabel.Text = 'Mouse2-ID';
            app.Mouse2ID = uieditfield(app.RightPanel, 'text');
            app.Mouse2ID.ValueChangedFcn = createCallbackFcn(app, @Mouse2IDValueChanged, true);
            app.Mouse2ID.Value = 'M2';
            app.SavePath = uieditfield(app.RightPanel, 'text');
            app.PathButton = uibutton(app.RightPanel, 'push');
            app.PathButton.ButtonPushedFcn = createCallbackFcn(app, @PathButtonPushed, true);
            app.PathButton.Text = 'Path';
            app.NoteTextAreaLabel = uilabel(app.RightPanel);
            app.NoteTextAreaLabel.Text = 'Note';
            app.NoteTextArea = uitextarea(app.RightPanel);
            app.WriteNoteButton = uibutton(app.RightPanel, 'push');
            app.WriteNoteButton.ButtonPushedFcn = createCallbackFcn(app, @WriteNoteButtonPushed, true);
            app.WriteNoteButton.Text = 'WriteNote';
            app.CurrentLogFile = uilabel(app.RightPanel);
            app.CurrentLogFile.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DualOpto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end