classdef PointGreyPreviewWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        PreviewAx  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Main App
        Imhandle % Axis Image handle
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            ROIPosition = mainapp.vid.ROIPosition;
            try
                app.Imhandle = imshow(imresize(imread('D:\Xingjian\Repositories\XZ_funkies\doge.jpg'),[ROIPosition(4),ROIPosition(3)]),'Parent',app.PreviewAx);
                pause(1.5);
            catch ME
                app.Imhandle = imshow(zeros([ROIPosition(4),ROIPosition(3)]),'Parent',app.PreviewAx);
            end
            preview(app.CallingApp.vid,app.Imhandle);
            app.PreviewAx.XLim = [0,ROIPosition(3)];
            app.PreviewAx.YLim = [0,ROIPosition(4)];
            app.PreviewAx.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.PreviewAx);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.CameraPreviewButton.Enable = 'on';
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PreviewAx = uiaxes(app.UIFigure);
            app.PreviewAx.XLimitMethod = 'tight';
            app.PreviewAx.YLimitMethod = 'tight';
            app.PreviewAx.ZLimitMethod = 'tight';
            app.PreviewAx.XAxisLocation = 'top';
            app.PreviewAx.XTick = [];
            app.PreviewAx.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PointGreyPreviewWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end