classdef VidRegi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CutfromFrameButton           matlab.ui.control.Button
        ResetVerButton               matlab.ui.control.Button
        ResetHorButton               matlab.ui.control.Button
        ReplacecurrentframeButton    matlab.ui.control.Button
        ChillingLabel                matlab.ui.control.Label
        ToEditField_2                matlab.ui.control.NumericEditField
        ToEditField_2Label           matlab.ui.control.Label
        ToEditField                  matlab.ui.control.NumericEditField
        ToEditFieldLabel             matlab.ui.control.Label
        FromEditField_2              matlab.ui.control.NumericEditField
        FromEditField_2Label         matlab.ui.control.Label
        FromEditField                matlab.ui.control.NumericEditField
        FromEditFieldLabel           matlab.ui.control.Label
        CopyVerButton                matlab.ui.control.Button
        CopyHorButton                matlab.ui.control.Button
        TemplateFrameEditField       matlab.ui.control.NumericEditField
        TemplateFrameEditFieldLabel  matlab.ui.control.Label
        CurrentFrameEditField        matlab.ui.control.NumericEditField
        CurrentFrameEditFieldLabel   matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        Slider                       matlab.ui.control.Slider
        SliderLabel                  matlab.ui.control.Label
        LoadVButton                  matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
        vertical                     matlab.ui.control.NumericEditField
        verticalEditFieldLabel       matlab.ui.control.Label
        horizontal                   matlab.ui.control.NumericEditField
        horizontalEditFieldLabel     matlab.ui.control.Label
        PreviewV                     matlab.ui.control.UIAxes
        InputV                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        rawV % raw video
        cur_F
        temp_F
        phandles
        horshift % vector storing horizontal shift values, negative = left, positive = right
        vershift % vector storing vertical shift values, positive = up, negative = down
        fpath % file path
        fname % file name
    end
    
    methods (Access = private)
        
        function updateInput(app)
            if ~isfield(app.phandles, 'input_v')
                app.phandles.input_v = imshow(app.rawV(:,:,:,app.cur_F), 'Parent',app.InputV)
            else
                set(app.phandles.input_v,'CData',app.rawV(:,:,:,app.cur_F));
            end
            drawnow
            
        end
        
        function updatePreview(app)
            shiftedF = imshift(app, app.cur_F);
            templateF = app.rawV(:,:,:,app.temp_F);


            if ~isfield(app.phandles, 'preview_V')
                app.phandles.preview_V = imshowpair(templateF,shiftedF,'checkerboard', 'Parent', app.PreviewV);
            else
                tempF = figure('Visible','off');
                tempA = axes('Parent',tempF);
                previewF = imshowpair(templateF, shiftedF, 'checkerboard', 'Parent', tempA);
                set(app.phandles.preview_V, 'CData', previewF.CData);
                close(tempF);
            end
            drawnow
        end
        
        function SI = imshift(app, F)
            % output shifted frame
            hor = app.horshift(F);
            ver = app.vershift(F);
            F_obj = app.rawV(:,:,:,F);
            [F_height,F_width,F_chann] = size(F_obj);
            
            % shift horizontally
            
            if hor < 0
                horpatch = zeros([F_width,abs(hor),F_chann]);
                F_obj_cp = cat(2,F_obj,horpatch);
                F_obj = F_obj_cp(:, abs(hor)+1:end, :);
            elseif hor>0
                horpatch = zeros([F_width,hor,F_chann]);
                F_obj_cp = cat(2,horpatch,F_obj);
                F_obj = F_obj_cp(:, 1:F_width,:);
            end
            
            % shift vertically
            if ver < 0
                verpatch = zeros([abs(ver),F_width,F_chann]);
                F_obj_cp = cat(1,verpatch,F_obj);
                F_obj = F_obj_cp(1:F_height,:,:);
            elseif ver>0
                verpatch = zeros([ver,F_width,F_chann]);
                F_obj_cp = cat(1,F_obj,verpatch);
                F_obj = F_obj_cp(ver+1:end,:,:);
            end
            SI = F_obj;
        end
        
        function results = imrescale(app, im)
            maxI = max(im,[],'all');
            minI = min(im,[],'all');
            results = (im-minI)/(maxI-minI)*100;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadVButton
        function LoadVButtonPushed(app, event)
            [fname, fpath] = uigetfile('.avi','select video you want to adjust');
            app.ChillingLabel.Text = 'Loading...';
            figure(app.UIFigure);
            vid = VideoReader([fpath,'\',fname]);
            app.fpath = fpath;
            app.fname = fname;
            app.rawV = read(vid);
            if size(app.rawV,3) == 1
                app.rawV = repmat(app.rawV,[1,1,3,1]);
            end       
            app.cur_F = 1;
            app.temp_F = 1;
            app.CurrentFrameEditField.Value = 1;
            app.TemplateFrameEditField.Value = 1;
            Fnum = size(app.rawV,4); 
            app.horshift = zeros(1,Fnum);
            app.vershift = zeros(1,Fnum);
            app.Slider.Limits = [1,Fnum];
            updateInput(app);
            updatePreview(app);
            app.ChillingLabel.Text = 'Chilling';
            
        end

        % Value changed function: TemplateFrameEditField
        function TemplateFrameEditFieldValueChanged(app, event)
            value = app.TemplateFrameEditField.Value;
            app.temp_F = round(value);
            updatePreview(app);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.cur_F = round(value);
            app.CurrentFrameEditField.Value = app.cur_F;
            app.horizontal.Value = app.horshift(app.cur_F);
            app.vertical.Value = app.vershift(app.cur_F);
            updateInput(app);
            updatePreview(app);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.cur_F = round(changingValue);
            updateInput(app);
            updatePreview(app);
        end

        % Value changed function: CurrentFrameEditField
        function CurrentFrameEditFieldValueChanged(app, event)
            value = app.CurrentFrameEditField.Value;
            app.cur_F = round(value);
            app.Slider.Value = app.cur_F;
            SliderValueChanged(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'leftarrow'
                    app.horizontal.Value = app.horizontal.Value - 1;
                    app.horshift(app.cur_F) = app.horshift(app.cur_F)-1;
                    updatePreview(app);
                case 'rightarrow'
                    app.horizontal.Value = app.horizontal.Value + 1;
                    app.horshift(app.cur_F) = app.horshift(app.cur_F)+1;
                    updatePreview(app);
                case 'uparrow'
                    app.vertical.Value = app.vertical.Value + 1;
                    app.vershift(app.cur_F) = app.vershift(app.cur_F)+1;
                    updatePreview(app);
                case 'downarrow'
                    app.vertical.Value = app.vertical.Value - 1;
                    app.vershift(app.cur_F) = app.vershift(app.cur_F)-1;
                    updatePreview(app);
                case 'a'
                    app.Slider.Value = app.Slider.Value-1;
                    app.cur_F = max(1,app.cur_F-1);
                    app.CurrentFrameEditField.Value = app.cur_F;
                    app.vertical.Value = app.vershift(app.cur_F);
                    app.horizontal.Value = app.horshift(app.cur_F);
                    updateInput(app);
                    updatePreview(app);
                case 'd'
                    app.Slider.Value = app.Slider.Value+1;
                    app.cur_F = min(app.cur_F + 1, app.Slider.Limits(2));
                    app.CurrentFrameEditField.Value = app.cur_F;
                    app.vertical.Value = app.vershift(app.cur_F);
                    app.horizontal.Value = app.horshift(app.cur_F);
                    updateInput(app);
                    updatePreview(app);
            end
        end

        % Value changed function: horizontal
        function horizontalValueChanged(app, event)
            value = app.horizontal.Value;
            app.horshift(app.cur_F) = round(value);
            updatePreview(app);
        end

        % Value changed function: vertical
        function verticalValueChanged(app, event)
            value = app.vertical.Value;
            app.vershift(app.cur_F) = round(value);
            updatePreview(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % apply shift to rawV;
            answer = questdlg('Apply shifts? There is NO going back!', ...
	        'Apply shifts','Yes','No','No');
            switch answer
                case 'Yes'
                    fw = waitbar(0,'applying shift to frames...');
                    F_shifted = find(app.horshift+app.vershift > 0);
                    for i = 1:length(F_shifted)
                        app.rawV(:,:,:,F_shifted(i)) = imshift(app,F_shifted(i));
                        waitbar(i/length(F_shifted), fw);
                    end
                    close(fw);
                    app.horshift = 0*app.horshift;
                    app.vershift = 0*app.vershift;
                    msgbox('Applied!','Apply shifts');
            end
            
        end

        % Button pushed function: CopyHorButton
        function CopyHorButtonPushed(app, event)
            from = round(app.FromEditField.Value);
            to = round(app.ToEditField.Value);
            app.horshift(from:to) = round(app.horizontal.Value);
            updatePreview(app);
        end

        % Button pushed function: CopyVerButton
        function CopyVerButtonPushed(app, event)
            from = round(app.FromEditField_2.Value);
            to = round(app.ToEditField_2.Value);
            app.vershift(from:to) = round(app.vertical.Value);
            updatePreview(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [name,path,savebool] = uiputfile([app.fpath,'\', 'msvideo_manually_adjusted.avi']);
            if savebool
                app.ChillingLabel.Text = ['Saving to ', [path,name]];
                viw = VideoWriter([path,name],'Grayscale AVI');
                open(viw);
                for i = 1:size(app.rawV,4)
                    writeVideo(viw, app.rawV(:,:,1,i));
                end
                close(viw);
                delete(viw);
            end
            app.ChillingLabel.Text = 'Chilling';
        end

        % Callback function
        function NegativeCheckBoxValueChanged(app, event)
            value = app.NegativeCheckBox.Value;
            updatePreview(app);
        end

        % Button pushed function: ReplacecurrentframeButton
        function ReplacecurrentframeButtonPushed(app, event)
            prompt = {'Replace with Frame# ?'};
            dlgtitle = 'Input frame number';
            definput = {num2str(app.cur_F)};
            input_params = inputdlg(prompt,dlgtitle,[1,15],definput);
            rep_F = str2num(input_params{1});
            app.rawV(:,:,:,app.cur_F) = app.rawV(:,:,:,rep_F);
            updatePreview(app);
            updateInput(app);
            msgbox(['Frame ' num2str(app.cur_F), ' replaced with ', num2str(rep_F)], 'Frame replacing');
        end

        % Button pushed function: ResetHorButton
        function ResetHorButtonPushed(app, event)
            answer = questdlg('Reset horizontal shift?', ...
	        'Reset horizontal','Yes','No','No');
            switch answer
                case 'Yes'
                    app.horshift = app.horshift * 0;
                    updatePreview(app);
                    msgbox('Reset!','Reset horizontal');
            end
            
        end

        % Button pushed function: ResetVerButton
        function ResetVerButtonPushed(app, event)
            answer = questdlg('Reset vertical shift?', ...
	        'Reset vertical','Yes','No','No');
            switch answer
                case 'Yes'
                    app.vershift = app.vershift * 0;
                    updatePreview(app);
                    msgbox('Reset!','Reset vertical');
            end
        end

        % Button pushed function: CutfromFrameButton
        function CutfromFrameButtonPushed(app, event)
            prompt = {'Cut video at Frame# ?'};
            dlgtitle = 'Input frame number';
            definput = {num2str(app.cur_F)};
            input_params = inputdlg(prompt,dlgtitle,[1,15],definput);
            cut_F = str2num(input_params{1});
            app.rawV = app.rawV(:,:,:,1:cut_F-1);
            if app.cur_F >= cut_F
                app.cur_F = cut_F-1;
            end
            app.Slider.Limits = [1, cut_F-1];
            app.Slider.Value = app.cur_F;
            SliderValueChanged(app);
            msgbox(['Video is cut at ' num2str(cut_F)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.InputV = uiaxes(app.UIFigure);
            title(app.InputV, 'Preview')
            app.InputV.XTick = [];
            app.InputV.YTick = [];
            app.PreviewV = uiaxes(app.UIFigure);
            title(app.PreviewV, 'Align View')
            app.PreviewV.XTick = [];
            app.PreviewV.YTick = [];
            app.horizontalEditFieldLabel = uilabel(app.UIFigure);
            app.horizontalEditFieldLabel.Text = 'horizontal';
            app.horizontal = uieditfield(app.UIFigure, 'numeric');
            app.horizontal.ValueChangedFcn = createCallbackFcn(app, @horizontalValueChanged, true);
            app.verticalEditFieldLabel = uilabel(app.UIFigure);
            app.verticalEditFieldLabel.Text = 'vertical';
            app.vertical = uieditfield(app.UIFigure, 'numeric');
            app.vertical.ValueChangedFcn = createCallbackFcn(app, @verticalValueChanged, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.LoadVButton = uibutton(app.UIFigure, 'push');
            app.LoadVButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVButtonPushed, true);
            app.LoadVButton.Text = 'Load V';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CurrentFrameEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentFrameEditFieldLabel.Text = 'Current Frame';
            app.CurrentFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentFrameEditField.ValueDisplayFormat = '%.0f';
            app.CurrentFrameEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentFrameEditFieldValueChanged, true);
            app.TemplateFrameEditFieldLabel = uilabel(app.UIFigure);
            app.TemplateFrameEditFieldLabel.Text = 'Template Frame';
            app.TemplateFrameEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemplateFrameEditField.ValueDisplayFormat = '%.0f';
            app.TemplateFrameEditField.ValueChangedFcn = createCallbackFcn(app, @TemplateFrameEditFieldValueChanged, true);
            app.CopyHorButton = uibutton(app.UIFigure, 'push');
            app.CopyHorButton.ButtonPushedFcn = createCallbackFcn(app, @CopyHorButtonPushed, true);
            app.CopyHorButton.Text = 'Copy Hor';
            app.CopyVerButton = uibutton(app.UIFigure, 'push');
            app.CopyVerButton.ButtonPushedFcn = createCallbackFcn(app, @CopyVerButtonPushed, true);
            app.CopyVerButton.Text = 'Copy Ver';
            app.FromEditFieldLabel = uilabel(app.UIFigure);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.UIFigure, 'numeric');
            app.FromEditField_2Label = uilabel(app.UIFigure);
            app.FromEditField_2Label.Text = 'From';
            app.FromEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ToEditFieldLabel = uilabel(app.UIFigure);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.UIFigure, 'numeric');
            app.ToEditField_2Label = uilabel(app.UIFigure);
            app.ToEditField_2Label.Text = 'To';
            app.ToEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ChillingLabel = uilabel(app.UIFigure);
            app.ChillingLabel.Text = 'Chilling';
            app.ReplacecurrentframeButton = uibutton(app.UIFigure, 'push');
            app.ReplacecurrentframeButton.ButtonPushedFcn = createCallbackFcn(app, @ReplacecurrentframeButtonPushed, true);
            app.ReplacecurrentframeButton.Text = 'Replace current frame';
            app.ResetHorButton = uibutton(app.UIFigure, 'push');
            app.ResetHorButton.ButtonPushedFcn = createCallbackFcn(app, @ResetHorButtonPushed, true);
            app.ResetHorButton.Text = 'Reset Hor';
            app.ResetVerButton = uibutton(app.UIFigure, 'push');
            app.ResetVerButton.ButtonPushedFcn = createCallbackFcn(app, @ResetVerButtonPushed, true);
            app.ResetVerButton.Text = 'Reset Ver';
            app.CutfromFrameButton = uibutton(app.UIFigure, 'push');
            app.CutfromFrameButton.ButtonPushedFcn = createCallbackFcn(app, @CutfromFrameButtonPushed, true);
            app.CutfromFrameButton.Text = 'Cut from Frame#';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VidRegi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end